classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        MessagesTextArea          matlab.ui.control.TextArea
        MessagesTextAreaLabel     matlab.ui.control.Label
        puaseButton               matlab.ui.control.Button
        connectedLamp             matlab.ui.control.Lamp
        PortDropDown              matlab.ui.control.DropDown
        PortDropDownLabel         matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        ScanQualityDropDown       matlab.ui.control.DropDown
        ScanQualityDropDownLabel  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        GenerateSTLButton         matlab.ui.control.Button
        SurfaceLabel              matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        s = NaN;% serial port variable
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortDropDown.Items = serialportlist;
            app.connectedLamp.Color = 'white';
            imwrite(255*ones([50,200]),'test.png')
        end

        % Button pushed function: StartButton
        function RunButtonPushed(app, event)
            clear app.UIAxes
            hold (app.UIAxes, 'on');
            h = 7;
            
            surface_map = 5e-2*ones([50,200]);

%             text = app.s.writeread('start');
            write(app.s,72,"uint8")
%             app.ListBox.Items = "data: "+data;
            for i = 1:20*200
                text = readline(app.s);
                try
                    data = 50-sscanf(text,'%f');
                    if data>0 && data<50
                        theta = 2*pi/200*mod(i,200);
                        x = data*cos(theta);
                        y = data*sin(theta);
                        z = h*floor(i/200);
                        plot3(app.UIAxes,x,y,z,'b.')
                        surface_map(floor(i/20)+1,mod(i,20)+1) = data;
                    end
                end
            end
            csvwrite('test.csv',surface_map);
        end

        % Callback function
        function GeneratefigureButtonPushed(app, event)
            fig = figure(1);
            fv = stlread('test.stl');
            patch(fig,fv,'FaceColor',       [0.8 0.8 1.0], ...
            'EdgeColor',       'none',        ...
            'FaceLighting',    'gouraud',     ...
            'AmbientStrength', 0.15);

            camlight('headlight');
            material('dull');

            axis('image');
            view([-135 35]);

        end

        % Button pushed function: puaseButton
        function puaseButtonPushed(app, event)
            if app.puaseButton.Text == "Puase"
                %% Figure out how to send the Puase cammand
                app.puaseButton.Text = "Reset";
                
            elseif app.puaseButton.Text == "Reset"
                app.MessagesTextArea.Value = 'Reseting';
                clear app.UIAxes
                imwrite(255*ones([50,200]),'test.png');
                app.puaseButton.Text = "Puase";
            end
        end

        % Callback function
        function PortDropDownValueChanged(app, event)
            app.PortDropDown.Items = serialportlist;
            port = app.PortDropDown.Value;
            try
                app.s = serialport(app.PortDropDown.Value,9600);
                java.lang.Thread.sleep(1000);
                
                % !!Function to test connection!!
                
                message = "Connect on port "+string(port);
                app.ListBox.Items = message;
                app.connectedLamp.Color = 'green';
            catch
                message = "Cannot connect to port: "+string(port);
                app.MessagesTextArea.Value = message;
                app.connectedLamp.Color = 'white';
            end
        end

        % Button pushed function: GenerateSTLButton
        function GenerateSTLButtonPushed(app, event)
            figure;

            python('mat2mesh.py')
            
            fv = stlread('test.stl');
            patch(fv,'FaceColor',       [0.8 0.8 1.0], ...
            'EdgeColor',       'none',        ...
            'FaceLighting',    'gouraud',     ...
            'AmbientStrength', 0.15);

            camlight('headlight');
            material('dull');

            axis('image');
            view([-135 35]);
        end

        % Callback function: PortDropDown, PortDropDown
        function PortDropDownOpening(app, event)
            app.PortDropDown.Items = serialportlist;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {523, 523};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ScanQualityDropDownLabel = uilabel(app.LeftPanel);
            app.ScanQualityDropDownLabel.Text = 'Scan Quality';
            app.ScanQualityDropDown = uidropdown(app.LeftPanel);
            app.ScanQualityDropDown.Items = {'', 'High', 'Medium', 'Low'};
            app.ScanQualityDropDown.Value = 'High';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PortDropDownLabel = uilabel(app.LeftPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.LeftPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortDropDownOpening, true);
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownOpening, true);
            app.PortDropDown.Value = {};
            app.connectedLamp = uilamp(app.LeftPanel);
            app.puaseButton = uibutton(app.LeftPanel, 'push');
            app.puaseButton.ButtonPushedFcn = createCallbackFcn(app, @puaseButtonPushed, true);
            app.puaseButton.Text = 'puase';
            app.MessagesTextAreaLabel = uilabel(app.LeftPanel);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Dot Cloud')
            xlabel(app.UIAxes, 'X (cm)')
            ylabel(app.UIAxes, 'Y (cm)')
            zlabel(app.UIAxes, 'Z (cm)')
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'test.png';
            app.SurfaceLabel = uilabel(app.RightPanel);
            app.SurfaceLabel.Text = 'Surface';
            app.GenerateSTLButton = uibutton(app.RightPanel, 'push');
            app.GenerateSTLButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSTLButtonPushed, true);
            app.GenerateSTLButton.Text = 'Generate STL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end