classdef application_yoga_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Image                  matlab.ui.control.Image
        Prediction_label       matlab.ui.control.Label
        PredictButton          matlab.ui.control.Button
        SurfaceMapLabel        matlab.ui.control.Label
        MessagesTextArea       matlab.ui.control.TextArea
        MessagesTextAreaLabel  matlab.ui.control.Label
        resetButton            matlab.ui.control.Button
        connectedLamp          matlab.ui.control.Lamp
        PortDropDown           matlab.ui.control.DropDown
        PortDropDownLabel      matlab.ui.control.Label
        StartButton            matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        GenerateSTLButton      matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        s = NaN;% serial port variable
        glob_reset = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortDropDown.Items = serialportlist;
            app.connectedLamp.Color = 'white';
        end

        % Button pushed function: StartButton
        function RunButtonPushed(app, event)
            cla(app.UIAxes);
            hold (app.UIAxes, 'on');
            h = .3;
            surface_map = [];
            z = zeros([1,200]);
            row_count = 0;
            write(app.s,72,'uint8');

            errors = 0;
            axis_limit = 0;
            row = zeros([1,200]);
            while 1
                row_count = row_count+1;
                while 1
                    try
                        text = readline(app.s);
                        if contains(text,"d")|| app.glob_reset
                            break
                        end
                        data = textscan(text,'%f');
                        data_m = data{1};
                        row(mod(fix(data_m),200)+1) = 47-data_m(2);

                        if data_m(1)>row_count*200
                            break
                        end
                        
                    catch
                        errors = errors+1;
                    end
                end

                if contains(text,"d") || app.glob_reset
                    break
                end
                fprintf("Errors %g",errors);
                row = filter_row(row);
                x = cos(linspace(0,2*pi,200)).*row;
                y = sin(linspace(0,2*pi,200)).*row;
                plot3(app.UIAxes,x,y,z,'b.','MarkerSize',1);
                axis_limit = max([axis_limit,max(abs(x)),max(abs(y)),z(1)]);
                app.UIAxes.XLim = [-1*axis_limit,axis_limit];
                app.UIAxes.YLim = [-1*axis_limit,axis_limit];
                app.UIAxes.ZLim = [0,axis_limit];
                surface_map = [surface_map;row];
                z=z+h;
            end
            
            if ~isempty(surface_map) && ~app.glob_reset
                writematrix(surface_map,'test.csv');
                app.MessagesTextArea.Value = ['Ready to Generate STL' newline 'Ready for prediction'];
            end
            
            app.glob_reset = 0;
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            write(app.s,82,"uint8");
            app.Image.ImageSource = 'blank.png';
            app.glob_reset = 1;
            app.MessagesTextArea.Value = '';
            app.Prediction_label.Text = 'Prediction: ';
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            try
                app.PortDropDown.Items = serialportlist;
                port = app.PortDropDown.Value;
                app.s = serialport(port,9600);
                app.connectedLamp.Color = 'green';
                java.lang.Thread.sleep(1000);
            catch
                app.MessagesTextArea.Value ="Invalid Connection";
            end
            
        end

        % Button pushed function: GenerateSTLButton
        function GenerateSTLButtonPushed(app, event)
            figure;
            pyrunfile('mat2mesh.py');
            fv = stlread('test.stl');
            patch(fv,'FaceColor',       [0.8 0.8 1.0], ...
            'EdgeColor',       'none',        ...
            'FaceLighting',    'gouraud',     ...
            'AmbientStrength', 0.15);

            camlight('headlight');
            material('dull');

            axis('image');
            view([-135 35]);
        end

        % Drop down opening function: PortDropDown
        function PortDropDownOpening(app, event)
            app.PortDropDown.Items = serialportlist;
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            temp = evalc('pyrunfile(''classify_Cube_Cyl.py'')');
            app.Image.ImageSource = 'temp.png';
            app.Prediction_label.Text = ['Object Type: ',temp] ;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {523, 523};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PortDropDownLabel = uilabel(app.LeftPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.LeftPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortDropDownOpening, true);
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.connectedLamp = uilamp(app.LeftPanel);
            app.resetButton = uibutton(app.LeftPanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.MessagesTextAreaLabel = uilabel(app.LeftPanel);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.LeftPanel);
            app.SurfaceMapLabel = uilabel(app.LeftPanel);
            app.SurfaceMapLabel.Text = 'Surface Map';
            app.PredictButton = uibutton(app.LeftPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.Prediction_label = uilabel(app.LeftPanel);
            app.Prediction_label.Text = 'Object Type: ';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'blank.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Dot Cloud')
            xlabel(app.UIAxes, 'X (cm)')
            ylabel(app.UIAxes, 'Y (cm)')
            zlabel(app.UIAxes, 'Z (cm)')
            app.GenerateSTLButton = uibutton(app.RightPanel, 'push');
            app.GenerateSTLButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSTLButtonPushed, true);
            app.GenerateSTLButton.Text = 'Generate STL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application_yoga_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end