classdef threeD_veiwer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        EchoButton                matlab.ui.control.Button
        refreshButton             matlab.ui.control.Button
        puaseButton               matlab.ui.control.Button
        connectedLamp             matlab.ui.control.Lamp
        GeneratefigureButton      matlab.ui.control.Button
        PortDropDown              matlab.ui.control.DropDown
        PortDropDownLabel         matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        StartButton               matlab.ui.control.Button
        ScanQualityDropDown       matlab.ui.control.DropDown
        ScanQualityDropDownLabel  matlab.ui.control.Label
        ObjectDropDown            matlab.ui.control.DropDown
        ObjectDropDownLabel       matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ObjectDropDown
        function ObjectDropDownValueChanged(app, event)
            value = app.ObjectDropDown.Value;

            if value=="Cube"
                fv = stlread("test312.stl");
            elseif value == "Cylinder"
                fv = stlread("cylinder.stl");
            elseif value =="Femur"
                fv = stlread("femur.stl");
            end
            scatter3(app.UIAxes,fv.vertices(:,1),fv.vertices(:,2),fv.vertices(:,3));

        end

        % Callback function: ListBox, StartButton
        function RunButtonPushed(app, event)
            out = python('test_scan.py','run');
            temp = cellstr(out);
            app.ListBox.Items(length(app.ListBox.Items)) = temp(1);
            if app.puaseButton.Text == "Reset"
                app.puaseButton.Text = "Puase";
            end
        end

        % Button pushed function: GeneratefigureButton
        function GeneratefigureButtonPushed(app, event)
            value = app.ObjectDropDown.Value;

            if value=="Cube"
                fv = stlread("test312.stl");
            elseif value == "Cylinder"
                fv = stlread("cylinder.stl");
            elseif value =="Femur"
                fv = stlread("femur.stl");
            end

            figure
            patch(fv,'FaceColor',       [0.8 0.8 1.0], ...
            'EdgeColor',       'none',        ...
            'FaceLighting',    'gouraud',     ...
            'AmbientStrength', 0.15);

            camlight('headlight');
            material('dull');

            axis('image');
            view([-135 35]);

        end

        % Button pushed function: puaseButton
        function puaseButtonPushed(app, event)
            if app.puaseButton.Text == "Puase";
                out = python('test_scan.py','puase');
                temp = cellstr(out);
                app.ListBox.Items(length(app.ListBox.Items)) = temp(1);
                
                app.puaseButton.Text = "Reset";
            elseif app.puaseButton.Text == "Reset"
                out = python('test_scan.py','reset');
                temp = cellstr(out);
                app.ListBox.Items(length(app.ListBox.Items)) = temp(1);
                
                app.puaseButton.Text = "Puase";
            end
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            app.PortDropDown.Items = serialportlist;
            port = app.PortDropDown.Value;
            message = "connect on port "+string(port);
            app.ListBox.Items = message;
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            app.PortDropDown.Items = serialportlist;
        end

        % Button pushed function: EchoButton
        function EchoButtonPushed(app, event)
            s = serialport(string(port),9600);
            answer = s.writeread('echo');
            app.ListBox = answer;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ObjectDropDownLabel = uilabel(app.LeftPanel);
            app.ObjectDropDownLabel.Text = 'Object';
            app.ObjectDropDown = uidropdown(app.LeftPanel);
            app.ObjectDropDown.Items = {'Cube', 'Femur', 'Cylinder'};
            app.ObjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectDropDownValueChanged, true);
            app.ObjectDropDown.Value = 'Cube';
            app.ScanQualityDropDownLabel = uilabel(app.LeftPanel);
            app.ScanQualityDropDownLabel.Text = 'Scan Quality';
            app.ScanQualityDropDown = uidropdown(app.LeftPanel);
            app.ScanQualityDropDown.Items = {'High', 'Medium', 'Low'};
            app.ScanQualityDropDown.Value = 'High';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {'item1'};
            app.ListBox.ItemsData = {'out'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.ListBox.Value = 'out';
            app.PortDropDownLabel = uilabel(app.LeftPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.LeftPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.GeneratefigureButton = uibutton(app.LeftPanel, 'push');
            app.GeneratefigureButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefigureButtonPushed, true);
            app.GeneratefigureButton.Text = 'Generate figure';
            app.connectedLamp = uilamp(app.LeftPanel);
            app.puaseButton = uibutton(app.LeftPanel, 'push');
            app.puaseButton.ButtonPushedFcn = createCallbackFcn(app, @puaseButtonPushed, true);
            app.puaseButton.Text = 'puase';
            app.refreshButton = uibutton(app.LeftPanel, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.EchoButton = uibutton(app.LeftPanel, 'push');
            app.EchoButton.ButtonPushedFcn = createCallbackFcn(app, @EchoButtonPushed, true);
            app.EchoButton.Text = 'Echo';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'cube7.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = threeD_veiwer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end